classdef Final_GUI_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DecodeButton                  matlab.ui.control.Button
        DecodedMessageLabel           matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        InsertTextLabel               matlab.ui.control.Label
        ChooseAudioFileLabel          matlab.ui.control.Label
        MorseCodeEncoderDecoderLabel  matlab.ui.control.Label
        PlayButton                    matlab.ui.control.Button
        DecodedTextLabel              matlab.ui.control.Label
        MorseCodeLabel                matlab.ui.control.Label
        UploadButton                  matlab.ui.control.Button
        SaveAudioButton               matlab.ui.control.Button
        GenerateButton                matlab.ui.control.Button
        InputTextField                matlab.ui.control.EditField
    end

    
properties (Access = public)
    AudioSignal           % Stores the generated Morse code audio
    SamplingFrequency   % Stores the sampling rate (e.g., 13000 Hz)
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
             % Define Morse code mappings
     morse = struct(...
    'a', '.-', 'b', '-...', 'c', '-.-.', 'd', '-..', 'e', '.', ...
    'f', '..-.', 'g', '--.', 'h', '....', 'i', '..', 'j', '.---', ...
    'k', '-.-', 'l', '.-..', 'm', '--', 'n', '-.', 'o', '---', ...
    'p', '.--.', 'q', '--.-', 'r', '.-.', 's', '...', 't', '-', ...
    'u', '..-', 'v', '...-', 'w', '.--', 'x', '-..-', 'y', '-.--', ...
    'z', '--..', ...
    'n1', '.----', 'n2', '..---', 'n3', '...--', 'n4', '....-', ...
    'n5', '.....', 'n6', '-....', 'n7', '--...', 'n8', '---..', ...
    'n9', '----.', 'n0', '-----');
    % Get user input
    text = lower(app.InputTextField.Value);

   % Get user input
    text = lower(app.InputTextField.Value);
    morseCode =[];

    % Generate Morse code
  %  for i = 1:length(text)
      %  currentChar = text(i);
      %  if currentChar == ' '
       %     morseCode = [morseCode, '/ '];
      %      morseCode=[morseCode ' ']
      %  elseif isstrprop(currentChar, 'alpha') % Check for letters
      %      morseCode = [morseCode, morse.(currentChar), ' '];
      %      morseCode=[morseCode ' ']
     %   elseif isstrprop(currentChar, 'digit') % Check for numbers
        %    fieldName = [ 't' ,currentChar]; % e.g., 'n1' for '1'
        %    morseCode = [morseCode, morse.(fieldName), ' '];
        %    morseCode=[morseCode ' ']
%
      %  end
        
   % end

for i =1:length(text)
    if text(i)== ' '
        morseCode=[morseCode '/'];
    elseif isvarname(text(i))
        morseCode= [morseCode getfield(morse,text(i))];
        morseCode= [morseCode ' '];
    elseif ~isempty(str2num(text(i)))
        morseCode= [morseCode getfield(morse,['n',text(i)])];
        morseCode= [morseCode ' '];
    elseif findstr(text(i),morse.sc)
        morseCode= [morseCode char(morse.scv(findstr(text(i),morse.sc)))];
          morseCode= [morseCode ' '];
    end
end
    app.SamplingFrequency.Text = morseCode;
    % Display Morse code
    app.MorseCodeLabel.Text = morseCode;

% Generate audio signal
Fs = 13000; % Sampling frequency

% Define dot (0.06s) and dash (0.18s)
dot = sin(2 * pi * 1000 * (0:1/Fs:0.06)');
dash = sin(2 * pi * 1000 * (0:1/Fs:0.18)');

% Define spaces (element durations)
ssp = zeros(round(0.06 * Fs), 1); % Short space (between symbols)
lsp = zeros(round(0.18 * Fs), 1); % Long space (between words)

% Initialize audio signal
ms = [];

% Build the signal
for i = 1:length(morseCode)
    switch morseCode(i)
        case '.'
            ms = [ms; dot; ssp];
        case '-'
            ms = [ms; dash; ssp];
        case ' '
            ms = [ms; lsp];
        case '/'
            ms = [ms; lsp; ssp];
        otherwise
            warning('Skipping invalid character: %s', morseCode(i));
    end
end
app.AudioSignal=ms


        end

        % Button pushed function: SaveAudioButton
        function SaveAudioButtonPushed(app, event)
    if isempty(app.AudioSignal)
        app.MorseCodeLabel.Text = 'Generate Morse code first!';
        return;
    end
    filename = 'morse_code.wav';
    audiowrite(filename, app.AudioSignal,13000);
    app.MorseCodeLabel.Text = ['Saved as ', filename];
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
   [file, path] = uigetfile('*.wav', 'Select Morse Code Audio File');
    if isequal(file, 0)
        app.DecodedTextLabel.Text = 'No file selected.';
        return;
    end
    [y, Fs] = audioread(fullfile(path, file));
    
    % Insert your Part 2 decoding logic here
    % Define tone frequency (use 0 to detect it automatically)
setToneFreq = 0; 

% Define Morse timing parameters
dit_len = 0.06; % Length of a dot (seconds)
dah_len = 3 * dit_len; % Length of a dash
spc_len = dit_len; % Space between symbols
spc_wrd = dah_len; % Space between words

% Tone frequency detection parameters
defToneFreq = 1000; % Default frequency (Hz)
minToneFreq = 500; % Minimum detectable frequency
maxToneFreq = 2500; % Maximum detectable frequency
defBandwidth = 5 / dit_len; % Bandwidth of filter
defDetThr = 0.75; % Detection threshold

% Initialize variables
nsmpl = length(y);
morseCode = cell(1, 1);
MsgToneFreq = zeros(1, 1);
ditstat = zeros(1, 4);
dahstat = zeros(1, 4);
dit_smpl = floor(dit_len * Fs);

curSig = y; % Assign input signal

% Tone detection
if setToneFreq == 0
    % Detect tone using Power Spectral Density (PSD)
    win_size = dit_smpl;
    m = win_size - 1;
    window = 0.5 - 0.5 * cos(2 * pi * (0:m)' / m); % Hanning window
    overlap = ceil(length(window) / 2);
    step = win_size - overlap;
    offset = 1:step:nsmpl - win_size;
    nslc = length(offset);
    
    P = zeros(win_size, nslc);
    for i = 1:nslc
        P(:, i) = curSig(offset(i):offset(i) + win_size - 1) .* window;
    end

    % Compute Fourier transform and extract positive frequencies
    P = fft(P, [], 1);
    iNyq = floor(win_size / 2);
    P = P(1:iNyq, :);
    P = mean(conj(P) .* P, 2);

    % Find peak frequency
    [~, im] = max(P);
    ToneFreq = Fs / win_size * (im - 1);
    sig_strength = P(im) / median(P);
    
    % Validate detected frequency
    if (ToneFreq < minToneFreq) || (ToneFreq > maxToneFreq) || (sig_strength < 2)
        warning('Unreliable tone detection! Check the signal.');
        ToneFreq = defToneFreq;
    end
else
    ToneFreq = setToneFreq; % Use predefined frequency
end

MsgToneFreq = ToneFreq;

% Filtering the signal around detected tone frequency
% Compute cutoff frequencies (ensure within valid range)
lowCutoff = max(0, (ToneFreq - defBandwidth / 2) / (Fs / 2));
highCutoff = min(1, (ToneFreq + defBandwidth / 2) / (Fs / 2));

% Validate frequency range
if lowCutoff >= highCutoff
    error('Invalid filter cutoff frequencies. Adjust the bandwidth or tone frequency.');
end

% Apply 5th order Butterworth bandpass filter
[b, a] = butter(5, [lowCutoff, highCutoff]);
curSig = filtfilt(b, a, curSig);

% Compute the envelope using the Hilbert transform
curSig = hilbert(curSig);
sigEnv = abs(curSig);

% Convert to binary signal based on threshold
level_threshold = defDetThr * max(sigEnv);
binSig = sigEnv >= level_threshold;

% Detect transitions in the signal
iSta = find(diff(binSig) == 1);
iEnd = find(diff(binSig) == -1);
len_on = iEnd - iSta;

% Estimate duration threshold for dit/dah classification
max_len = max(len_on);
min_len = min(len_on);
thr = (max_len + min_len) / 2;

% Identify dots (dits)
bDit = len_on < thr;
iDit = find(bDit);
avrg_dit_len = mean(len_on(iDit));
std_dit_len = std(len_on(iDit));

% Remove outliers
iout = abs(len_on(iDit) - avrg_dit_len) > 6 * std_dit_len;
bDit(iout) = 0;

% Recalculate
iDit = find(bDit);
avrg_dit_len = mean(len_on(iDit));
avrg_dit = avrg_dit_len / Fs;
std_dit_len = std(len_on(iDit));
ditstat = [avrg_dit, std_dit_len / Fs, min(len_on(iDit)) / Fs, max(len_on(iDit)) / Fs];

% Identify dashes (dahs)
bDah = len_on > thr;
iDah = find(bDah);
avrg_dah_len = mean(len_on(iDah));
std_dah_len = std(len_on(iDah));

% Remove outliers
iout = abs(len_on(iDah) - avrg_dah_len) > 6 * std_dah_len;
bDah(iout) = 0;

% Recalculate
iDah = find(bDah);
avrg_dah_len = mean(len_on(iDah));
avrg_dah = avrg_dah_len / Fs;
std_dah_len = std(len_on(iDah));
dahstat = [avrg_dah, std_dah_len / Fs, min(len_on(iDah)) / Fs, max(len_on(iDah)) / Fs];

% Detect silence intervals
len_off = iSta(2:end) - iEnd(1:end-1);
len_off(end+1) = length(binSig) - iEnd(end);

% Decode Morse Code
morseC = '';
curMsg = '';

for idx = 1:length(len_on)
    if bDah(idx)
        morseC = strcat(morseC, "-");
    elseif bDit(idx)
        morseC = strcat(morseC, ".");
    end
    

    % Append the Morse character
    if idx == length(len_on) || len_off(idx) > 0
        curMsg = strcat(curMsg, morseC);
        morseC = ''; % Reset for next character
    end

    % Insert space if a silent interval is detected
    if len_off(idx) > (10 * avrg_dit_len) % Adjust this threshold as needed
        curMsg = strcat(curMsg, " ");
    end

    % Word separation
    if len_off(idx) > 4 * avrg_dah_len
        if idx < length(len_on)
            curMsg = strcat(curMsg, ' / '); % '/' represents word separator
        end
    end
end

morseCode{1} = curMsg;

% Convert Morse code to string and display result
morseCode = char(morseCode{:});
   
    app.DecodedTextLabel.Text = app.SamplingFrequency.Text;
    

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
        sound(app.AudioSignal, 13000)
      
        end

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
            % Define Morse code mappings (same as your struct)
% Define Morse code mappings (including special characters)
morse = struct;
% Letters
morse.a='.-'; morse.b='-...'; morse.c='-.-.'; morse.d='-..'; morse.e='.';
morse.f='..-.'; morse.g='--.'; morse.h='....'; morse.i='..'; morse.j='.---';
morse.k='-.-'; morse.l='.-..'; morse.m='--'; morse.n='-.'; morse.o='---';
morse.p='.--.'; morse.q='--.-'; morse.r='.-.'; morse.s='...'; morse.t='-';
morse.u='..-'; morse.v='...-'; morse.w='.--'; morse.x='-..-'; morse.y='-.--';
morse.z='--..';
% Numbers
morse.q1='.----'; morse.q2='..---'; morse.q3='...--'; morse.q4='....-';
morse.q5='.....'; morse.q6='-....'; morse.q7='--...'; morse.q8='---..';
morse.q9='----.'; morse.q0='-----';
% Special characters (.,?/)
morse.period='.-.-.-';    % . → .-.-.-
morse.comma='--..--';     % , → --..--
morse.question='..--..';  % ? → ..--..
morse.slash='-..-.';      % / → -..-.

% Create reverse map (Morse code → character)
morseMap = containers.Map;
fields = fieldnames(morse);
for i = 1:length(fields)
    code = morse.(fields{i});
    if startsWith(fields{i}, 'q')
        char = fields{i}(2:end);  % e.g., 'n1' → '1'
    elseif strcmp(fields{i}, 'period')
        char = '.';
    elseif strcmp(fields{i}, 'comma')
        char = ',';
    elseif strcmp(fields{i}, 'question')
        char = '?';
    elseif strcmp(fields{i}, 'slash')
        char = '/';
    else
        char = fields{i};  % Letters (a-z)
    end
    morseMap(code) = char;
end

% Input Morse code (words separated by '/', characters by ' ')
morseCode1 = app.SamplingFrequency.Text

% Split into words and characters
words = strsplit(morseCode1, '/');
decodedText = '';

for i = 1:length(words)
    word = strtrim(words{i});
    if isempty(word), continue; end
    chars = strsplit(word, ' ');
    for j = 1:length(chars)
        code = strtrim(chars{j});
        if isempty(code), continue; end
        if morseMap.isKey(code)
            decodedText = [decodedText, morseMap(code)];
        else
            decodedText = [decodedText, '?']; % Handle invalid codes
        end
    end
    decodedText = [decodedText, ' ']; % Add space between words
end

% Remove trailing space and display
decodedText = strtrim(decodedText);
app.DecodedMessageLabel.Text=decodedText
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputTextField = uieditfield(app.UIFigure, 'text');
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Encode';
            app.SaveAudioButton = uibutton(app.UIFigure, 'push');
            app.SaveAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioButtonPushed, true);
            app.SaveAudioButton.Text = 'Save the Audio';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Browse';
            app.MorseCodeLabel = uilabel(app.UIFigure);
            app.MorseCodeLabel.Text = 'Text to Morse';
            app.DecodedTextLabel = uilabel(app.UIFigure);
            app.DecodedTextLabel.Text = 'Morse Code';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.MorseCodeEncoderDecoderLabel = uilabel(app.UIFigure);
            app.MorseCodeEncoderDecoderLabel.Text = 'Morse Code Encoder & Decoder';
            app.ChooseAudioFileLabel = uilabel(app.UIFigure);
            app.ChooseAudioFileLabel.Text = 'Choose Audio File...';
            app.InsertTextLabel = uilabel(app.UIFigure);
            app.InsertTextLabel.Text = 'Insert Text';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DecodedMessageLabel = uilabel(app.UIFigure);
            app.DecodedMessageLabel.Text = 'Decoded Message';
            app.DecodeButton = uibutton(app.UIFigure, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_GUI_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end